classdef VisualizeShading_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CellTab                       matlab.ui.container.Tab
        VLabel_4                      matlab.ui.control.Label
        Wm2Label_4                    matlab.ui.control.Label
        BreakdownVoltage              matlab.ui.control.NumericEditField
        Irradiance                    matlab.ui.control.NumericEditField
        Image3                        matlab.ui.control.Image
        BreakdownvoltageLabel_4       matlab.ui.control.Label
        IrradianceLabel               matlab.ui.control.Label
        figCellReverse                matlab.ui.control.UIAxes
        figCellForward                matlab.ui.control.UIAxes
        StringTab                     matlab.ui.container.Tab
        InidicatewhichcellareshadedLabel  matlab.ui.control.Label
        VoltagerangeVLabel            matlab.ui.control.Label
        VoltageRangeString            matlab.ui.control.RangeSlider
        VLabel                        matlab.ui.control.Label
        Wm2Label                      matlab.ui.control.Label
        BreakdownVoltage_String       matlab.ui.control.NumericEditField
        Irradiance_String             matlab.ui.control.NumericEditField
        ShadedString_10               matlab.ui.control.CheckBox
        ShadedString_9                matlab.ui.control.CheckBox
        ShadedString_8                matlab.ui.control.CheckBox
        ShadedString_7                matlab.ui.control.CheckBox
        ShadedString_6                matlab.ui.control.CheckBox
        ShadedString_5                matlab.ui.control.CheckBox
        ShadedString_4                matlab.ui.control.CheckBox
        ShadedString_3                matlab.ui.control.CheckBox
        ShadedString_2                matlab.ui.control.CheckBox
        ShadedString_1                matlab.ui.control.CheckBox
        Image                         matlab.ui.control.Image
        BreakdownvoltageLabel         matlab.ui.control.Label
        IrradianceshadedcellsLabel    matlab.ui.control.Label
        figString                     matlab.ui.control.UIAxes
        ModuleseriesTab               matlab.ui.container.Tab
        InidicatewhichcellareshadedLabel_2  matlab.ui.control.Label
        String3Label                  matlab.ui.control.Label
        String2Label                  matlab.ui.control.Label
        String1Label                  matlab.ui.control.Label
        VLabel_2                      matlab.ui.control.Label
        Wm2Label_2                    matlab.ui.control.Label
        BreakdownVoltage_Series       matlab.ui.control.NumericEditField
        Irradiance_Series             matlab.ui.control.NumericEditField
        BreakdownvoltageLabel_2       matlab.ui.control.Label
        IrradianceshadedcellsLabel_2  matlab.ui.control.Label
        ShadedSeries_11               matlab.ui.control.CheckBox
        ShadedSeries_10               matlab.ui.control.CheckBox
        ShadedSeries_9                matlab.ui.control.CheckBox
        ShadedSeries_8                matlab.ui.control.CheckBox
        ShadedSeries_7                matlab.ui.control.CheckBox
        ShadedSeries_6                matlab.ui.control.CheckBox
        ShadedSeries_5                matlab.ui.control.CheckBox
        ShadedSeries_4                matlab.ui.control.CheckBox
        ShadedSeries_3                matlab.ui.control.CheckBox
        ShadedSeries_2                matlab.ui.control.CheckBox
        ShadedSeries_12               matlab.ui.control.CheckBox
        ShadedSeries_1                matlab.ui.control.CheckBox
        ShadedSeries_23               matlab.ui.control.CheckBox
        ShadedSeries_22               matlab.ui.control.CheckBox
        ShadedSeries_21               matlab.ui.control.CheckBox
        ShadedSeries_20               matlab.ui.control.CheckBox
        ShadedSeries_19               matlab.ui.control.CheckBox
        ShadedSeries_18               matlab.ui.control.CheckBox
        ShadedSeries_17               matlab.ui.control.CheckBox
        ShadedSeries_16               matlab.ui.control.CheckBox
        ShadedSeries_15               matlab.ui.control.CheckBox
        ShadedSeries_14               matlab.ui.control.CheckBox
        ShadedSeries_24               matlab.ui.control.CheckBox
        ShadedSeries_13               matlab.ui.control.CheckBox
        ShadedSeries_35               matlab.ui.control.CheckBox
        ShadedSeries_34               matlab.ui.control.CheckBox
        ShadedSeries_33               matlab.ui.control.CheckBox
        ShadedSeries_32               matlab.ui.control.CheckBox
        ShadedSeries_31               matlab.ui.control.CheckBox
        ShadedSeries_30               matlab.ui.control.CheckBox
        ShadedSeries_29               matlab.ui.control.CheckBox
        ShadedSeries_28               matlab.ui.control.CheckBox
        ShadedSeries_27               matlab.ui.control.CheckBox
        ShadedSeries_26               matlab.ui.control.CheckBox
        ShadedSeries_36               matlab.ui.control.CheckBox
        ShadedSeries_25               matlab.ui.control.CheckBox
        ShadedSeries_47               matlab.ui.control.CheckBox
        ShadedSeries_46               matlab.ui.control.CheckBox
        ShadedSeries_45               matlab.ui.control.CheckBox
        ShadedSeries_44               matlab.ui.control.CheckBox
        ShadedSeries_43               matlab.ui.control.CheckBox
        ShadedSeries_42               matlab.ui.control.CheckBox
        ShadedSeries_41               matlab.ui.control.CheckBox
        ShadedSeries_40               matlab.ui.control.CheckBox
        ShadedSeries_39               matlab.ui.control.CheckBox
        ShadedSeries_38               matlab.ui.control.CheckBox
        ShadedSeries_48               matlab.ui.control.CheckBox
        ShadedSeries_37               matlab.ui.control.CheckBox
        ShadedSeries_59               matlab.ui.control.CheckBox
        ShadedSeries_58               matlab.ui.control.CheckBox
        ShadedSeries_57               matlab.ui.control.CheckBox
        ShadedSeries_56               matlab.ui.control.CheckBox
        ShadedSeries_55               matlab.ui.control.CheckBox
        ShadedSeries_54               matlab.ui.control.CheckBox
        ShadedSeries_53               matlab.ui.control.CheckBox
        ShadedSeries_52               matlab.ui.control.CheckBox
        ShadedSeries_51               matlab.ui.control.CheckBox
        ShadedSeries_50               matlab.ui.control.CheckBox
        ShadedSeries_60               matlab.ui.control.CheckBox
        ShadedSeries_49               matlab.ui.control.CheckBox
        ShadedSeries_71               matlab.ui.control.CheckBox
        ShadedSeries_70               matlab.ui.control.CheckBox
        ShadedSeries_69               matlab.ui.control.CheckBox
        ShadedSeries_68               matlab.ui.control.CheckBox
        ShadedSeries_67               matlab.ui.control.CheckBox
        ShadedSeries_66               matlab.ui.control.CheckBox
        ShadedSeries_65               matlab.ui.control.CheckBox
        ShadedSeries_64               matlab.ui.control.CheckBox
        ShadedSeries_63               matlab.ui.control.CheckBox
        ShadedSeries_62               matlab.ui.control.CheckBox
        ShadedSeries_72               matlab.ui.control.CheckBox
        ShadedSeries_61               matlab.ui.control.CheckBox
        Image2                        matlab.ui.control.Image
        figSeries                     matlab.ui.control.UIAxes
        ModulebutterflyTab            matlab.ui.container.Tab
        InidicatewhichcellareshadedLabel_3  matlab.ui.control.Label
        PlotaandbseperateCheckBox     matlab.ui.control.CheckBox
        String3bLabel                 matlab.ui.control.Label
        String2bLabel                 matlab.ui.control.Label
        String1bLabel                 matlab.ui.control.Label
        String3aLabel                 matlab.ui.control.Label
        String2aLabel                 matlab.ui.control.Label
        String1aLabel                 matlab.ui.control.Label
        VLabel_3                      matlab.ui.control.Label
        Wm2Label_3                    matlab.ui.control.Label
        BreakdownVoltage_Butterfly    matlab.ui.control.NumericEditField
        Irradiance_Butterfly          matlab.ui.control.NumericEditField
        BreakdownvoltageLabel_3       matlab.ui.control.Label
        IrradianceshadedcellsLabel_3  matlab.ui.control.Label
        ShadedButterfly_11            matlab.ui.control.CheckBox
        ShadedButterfly_10            matlab.ui.control.CheckBox
        ShadedButterfly_9             matlab.ui.control.CheckBox
        ShadedButterfly_8             matlab.ui.control.CheckBox
        ShadedButterfly_7             matlab.ui.control.CheckBox
        ShadedButterfly_6             matlab.ui.control.CheckBox
        ShadedButterfly_5             matlab.ui.control.CheckBox
        ShadedButterfly_4             matlab.ui.control.CheckBox
        ShadedButterfly_3             matlab.ui.control.CheckBox
        ShadedButterfly_2             matlab.ui.control.CheckBox
        ShadedButterfly_12            matlab.ui.control.CheckBox
        ShadedButterfly_1             matlab.ui.control.CheckBox
        ShadedButterfly_23            matlab.ui.control.CheckBox
        ShadedButterfly_22            matlab.ui.control.CheckBox
        ShadedButterfly_21            matlab.ui.control.CheckBox
        ShadedButterfly_20            matlab.ui.control.CheckBox
        ShadedButterfly_19            matlab.ui.control.CheckBox
        ShadedButterfly_18            matlab.ui.control.CheckBox
        ShadedButterfly_17            matlab.ui.control.CheckBox
        ShadedButterfly_16            matlab.ui.control.CheckBox
        ShadedButterfly_15            matlab.ui.control.CheckBox
        ShadedButterfly_14            matlab.ui.control.CheckBox
        ShadedButterfly_24            matlab.ui.control.CheckBox
        ShadedButterfly_13            matlab.ui.control.CheckBox
        ShadedButterfly_35            matlab.ui.control.CheckBox
        ShadedButterfly_34            matlab.ui.control.CheckBox
        ShadedButterfly_33            matlab.ui.control.CheckBox
        ShadedButterfly_32            matlab.ui.control.CheckBox
        ShadedButterfly_31            matlab.ui.control.CheckBox
        ShadedButterfly_30            matlab.ui.control.CheckBox
        ShadedButterfly_29            matlab.ui.control.CheckBox
        ShadedButterfly_28            matlab.ui.control.CheckBox
        ShadedButterfly_27            matlab.ui.control.CheckBox
        ShadedButterfly_26            matlab.ui.control.CheckBox
        ShadedButterfly_36            matlab.ui.control.CheckBox
        ShadedButterfly_25            matlab.ui.control.CheckBox
        ShadedButterfly_47            matlab.ui.control.CheckBox
        ShadedButterfly_46            matlab.ui.control.CheckBox
        ShadedButterfly_45            matlab.ui.control.CheckBox
        ShadedButterfly_44            matlab.ui.control.CheckBox
        ShadedButterfly_43            matlab.ui.control.CheckBox
        ShadedButterfly_42            matlab.ui.control.CheckBox
        ShadedButterfly_41            matlab.ui.control.CheckBox
        ShadedButterfly_40            matlab.ui.control.CheckBox
        ShadedButterfly_39            matlab.ui.control.CheckBox
        ShadedButterfly_38            matlab.ui.control.CheckBox
        ShadedButterfly_48            matlab.ui.control.CheckBox
        ShadedButterfly_37            matlab.ui.control.CheckBox
        ShadedButterfly_59            matlab.ui.control.CheckBox
        ShadedButterfly_58            matlab.ui.control.CheckBox
        ShadedButterfly_57            matlab.ui.control.CheckBox
        ShadedButterfly_56            matlab.ui.control.CheckBox
        ShadedButterfly_55            matlab.ui.control.CheckBox
        ShadedButterfly_54            matlab.ui.control.CheckBox
        ShadedButterfly_53            matlab.ui.control.CheckBox
        ShadedButterfly_52            matlab.ui.control.CheckBox
        ShadedButterfly_51            matlab.ui.control.CheckBox
        ShadedButterfly_50            matlab.ui.control.CheckBox
        ShadedButterfly_60            matlab.ui.control.CheckBox
        ShadedButterfly_49            matlab.ui.control.CheckBox
        ShadedButterfly_71            matlab.ui.control.CheckBox
        ShadedButterfly_70            matlab.ui.control.CheckBox
        ShadedButterfly_69            matlab.ui.control.CheckBox
        ShadedButterfly_68            matlab.ui.control.CheckBox
        ShadedButterfly_67            matlab.ui.control.CheckBox
        ShadedButterfly_66            matlab.ui.control.CheckBox
        ShadedButterfly_65            matlab.ui.control.CheckBox
        ShadedButterfly_64            matlab.ui.control.CheckBox
        ShadedButterfly_63            matlab.ui.control.CheckBox
        ShadedButterfly_62            matlab.ui.control.CheckBox
        ShadedButterfly_72            matlab.ui.control.CheckBox
        ShadedButterfly_61            matlab.ui.control.CheckBox
        Image2_2                      matlab.ui.control.Image
        figButterfly                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app] = ExecuteScriptCell(app); 
            [app] = ExecuteScriptString(app);
            [app] = ExecuteScriptSeries(app);
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: Irradiance
        function ChangeIrradiance_Cell(app, event)
            [app] = ExecuteScriptCell(app);
        end

        % Value changed function: BreakdownVoltage
        function ChangeBreakdownVoltage_Cell(app, event)
            [app] = ExecuteScriptCell(app);
        end

        % Value changed function: Irradiance_String
        function ChangeIrradiance_String(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: BreakdownVoltage_String
        function ChangeBreakdownVoltage_String(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_1
        function ChangeStringShaded_1(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_2
        function ChangeStringShaded_2(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_3
        function ChangeStringShaded_3(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_4
        function ChangeStringShaded_4(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_5
        function ChangeStringShaded_5(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_6
        function ChangeStringShaded_6(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_7
        function ChangeStringShaded_7(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_8
        function ChangeStringShaded_8(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_9
        function ChangeStringShaded_9(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: ShadedString_10
        function ChangeStringShaded_10(app, event)
            [app] = ExecuteScriptString(app);
        end

        % Value changed function: VoltageRangeString
        function ChangeVrangeString(app, event)
            [app] = ExecuteScriptString(app);            
        end

        % Value changed function: ShadedSeries_61
        function ChangeSeriesShaded_61(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_72
        function ChangeSeriesShaded_72(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_62
        function ChangeSeriesShaded_62(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_63
        function ChangeSeriesShaded_63(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_64
        function ChangeSeriesShaded_64(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_65
        function ChangeSeriesShaded_65(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_66
        function ChangeSeriesShaded_66(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_67
        function ChangeSeriesShaded_67(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_68
        function ChangeSeriesShaded_68(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_69
        function ChangeSeriesShaded_69(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_70
        function ChangeSeriesShaded_70(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_71
        function ChangeSeriesShaded_71(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_49
        function ChangeSeriesShaded_49(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_60
        function ChangeSeriesShaded_60(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_50
        function ChangeSeriesShaded_50(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_51
        function ChangeSeriesShaded_51(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_52
        function ChangeSeriesShaded_52(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_53
        function ChangeSeriesShaded_53(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_54
        function ChangeSeriesShaded_54(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_55
        function ChangeSeriesShaded_55(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_56
        function ChangeSeriesShaded_56(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_57
        function ChangeSeriesShaded_57(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_58
        function ChangeSeriesShaded_58(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_59
        function ChangeSeriesShaded_59(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_37
        function ChangeSeriesShaded_37(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_48
        function ChangeSeriesShaded_48(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_38
        function ChangeSeriesShaded_38(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_39
        function ChangeSeriesShaded_39(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_40
        function ChangeSeriesShaded_40(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_41
        function ChangeSeriesShaded_41(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_42
        function ChangeSeriesShaded_42(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_43
        function ChangeSeriesShaded_43(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_44
        function ChangeSeriesShaded_44(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_45
        function ChangeSeriesShaded_45(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_46
        function ChangeSeriesShaded_46(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_47
        function ChangeSeriesShaded_47(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_25
        function ChangeSeriesShaded_25(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_36
        function ChangeSeriesShaded_36(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_26
        function ChangeSeriesShaded_26(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_27
        function ChangeSeriesShaded_27(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_28
        function ChangeSeriesShaded_28(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_29
        function ChangeSeriesShaded_29(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_30
        function ChangeSeriesShaded_30(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_31
        function ChangeSeriesShaded_31(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_32
        function ChangeSeriesShaded_32(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_33
        function ChangeSeriesShaded_33(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_34
        function ChangeSeriesShaded_34(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_35
        function ChangeSeriesShaded_35(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_13
        function ChangeSeriesShaded_13(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_24
        function ChangeSeriesShaded_24(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_14
        function ChangeSeriesShaded_14(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_15
        function ChangeSeriesShaded_15(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_16
        function ChangeSeriesShaded_16(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_17
        function ChangeSeriesShaded_17(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_18
        function ChangeSeriesShaded_18(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_19
        function ChangeSeriesShaded_19(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_20
        function ChangeSeriesShaded_20(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_21
        function ChangeSeriesShaded_21(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_22
        function ChangeSeriesShaded_22(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_23
        function ChangeSeriesShaded_23(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_1
        function ChangeSeriesShaded_1(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_12
        function ChangeSeriesShaded_12(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_2
        function ChangeSeriesShaded_2(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_3
        function ChangeSeriesShaded_3(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_4
        function ChangeSeriesShaded_4(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_5
        function ChangeSeriesShaded_5(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_6
        function ChangeSeriesShaded_6(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_7
        function ChangeSeriesShaded_7(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_8
        function ChangeSeriesShaded_8(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_9
        function ChangeSeriesShaded_9(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_10
        function ChangeSeriesShaded_10(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedSeries_11
        function ChangeSeriesShaded_11(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: Irradiance_Series
        function ChangeIrradiance_Series(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: BreakdownVoltage_Series
        function ChangeBreakdownVoltage_Series(app, event)
            [app] = ExecuteScriptSeries(app);
        end

        % Value changed function: ShadedButterfly_11
        function ChangeButterflyShaded_11(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_10
        function ChangeButterflyShaded_10(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_9
        function ChangeButterflyShaded_9(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_7
        function ChangeButterflyShaded_7(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_6
        function ChangeButterflyShaded_6(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_5
        function ChangeButterflyShaded_5(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_4
        function ChangeButterflyShaded_4(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_3
        function ChangeButterflyShaded_3(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_2
        function ChangeButterflyShaded_2(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_12
        function ChangeButterflyShaded_12(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_1
        function ChangeButterflyShaded_1(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_23
        function ChangeButterflyShaded_23(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_22
        function ChangeButterflyShaded_22(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_21
        function ChangeButterflyShaded_21(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_20
        function ChangeButterflyShaded_20(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_19
        function ChangeButterflyShaded_19(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_18
        function ChangeButterflyShaded_18(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_17
        function ChangeButterflyShaded_17(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_16
        function ChangeButterflyShaded_16(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_15
        function ChangeButterflyShaded_15(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_14
        function ChangeButterflyShaded_14(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_24
        function ChangeButterflyShaded_24(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_13
        function ChangeButterflyShaded_13(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_35
        function ChangeButterflyShaded_35(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_34
        function ChangeButterflyShaded_34(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_33
        function ChangeButterflyShaded_33(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_32
        function ChangeButterflyShaded_32(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_31
        function ChangeButterflyShaded_31(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_30
        function ChangeButterflyShaded_30(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_29
        function ChangeButterflyShaded_29(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_28
        function ChangeButterflyShaded_28(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_27
        function ChangeButterflyShaded_27(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_26
        function ChangeButterflyShaded_26(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_36
        function ChangeButterflyShaded_36(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_25
        function ChangeButterflyShaded_25(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_47
        function ChangeButterflyShaded_47(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_46
        function ChangeButterflyShaded_46(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_45
        function ChangeButterflyShaded_45(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_44
        function ChangeButterflyShaded_44(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_43
        function ChangeButterflyShaded_43(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_42
        function ChangeButterflyShaded_42(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_41
        function ChangeButterflyShaded_41(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_40
        function ChangeButterflyShaded_40(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_39
        function ChangeButterflyShaded_39(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_38
        function ChangeButterflyShaded_38(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_48
        function ChangeButterflyShaded_48(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_37
        function ChangeButterflyShaded_37(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_59
        function ChangeButterflyShaded_59(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_58
        function ChangeButterflyShaded_58(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_57
        function ChangeButterflyShaded_57(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_56
        function ChangeButterflyShaded_56(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_55
        function ChangeButterflyShaded_55(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_54
        function ChangeButterflyShaded_54(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_53
        function ChangeButterflyShaded_53(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_52
        function ChangeButterflyShaded_52(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_51
        function ChangeButterflyShaded_51(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_50
        function ChangeButterflyShaded_50(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_60
        function ChangeButterflyShaded_60(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_49
        function ChangeButterflyShaded_49(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_71
        function ChangeButterflyShaded_71(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_70
        function ChangeButterflyShaded_70(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_69
        function ChangeButterflyShaded_69(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_68
        function ChangeButterflyShaded_68(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_67
        function ChangeButterflyShaded_67(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_66
        function ChangeButterflyShaded_66(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_65
        function ChangeButterflyShaded_65(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_64
        function ChangeButterflyShaded_64(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_63
        function ChangeButterflyShaded_63(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_62
        function ChangeButterflyShaded_62(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_72
        function ChangeButterflyShaded_72(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_61
        function ChangeButterflyShaded_61(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: Irradiance_Butterfly
        function ChangeIrradiance_Butterfly(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: BreakdownVoltage_Butterfly
        function changeBreakdownVoltage_Butterfly(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: ShadedButterfly_8
        function ChangeButterflyShaded_8(app, event)
            [app] = ExecuteScriptButterfly(app);
        end

        % Value changed function: PlotaandbseperateCheckBox
        function ChangePlotSeperate(app, event)
            [app] = ExecuteScriptButterfly(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CellTab = uitab(app.TabGroup);
            app.CellTab.Title = 'Cell';
            app.figCellForward = uiaxes(app.CellTab);
            title(app.figCellForward, 'Title')
            xlabel(app.figCellForward, 'X')
            ylabel(app.figCellForward, 'Y')
            zlabel(app.figCellForward, 'Z')
            colormap(app.figCellForward, 'parula')
            app.figCellReverse = uiaxes(app.CellTab);
            title(app.figCellReverse, 'Title')
            xlabel(app.figCellReverse, 'X')
            ylabel(app.figCellReverse, 'Y')
            zlabel(app.figCellReverse, 'Z')
            colormap(app.figCellReverse, 'parula')
            app.IrradianceLabel = uilabel(app.CellTab);
            app.IrradianceLabel.Text = 'Irradiance';
            app.BreakdownvoltageLabel_4 = uilabel(app.CellTab);
            app.BreakdownvoltageLabel_4.Text = 'Breakdown voltage';
            app.Image3 = uiimage(app.CellTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Figures', 'FigureCellOperation.png');
            app.Irradiance = uieditfield(app.CellTab, 'numeric');
            app.Irradiance.Limits = [0 1500];
            app.Irradiance.ValueChangedFcn = createCallbackFcn(app, @ChangeIrradiance_Cell, true);
            app.Irradiance.Value = 1000;
            app.BreakdownVoltage = uieditfield(app.CellTab, 'numeric');
            app.BreakdownVoltage.Limits = [-50 0];
            app.BreakdownVoltage.ValueChangedFcn = createCallbackFcn(app, @ChangeBreakdownVoltage_Cell, true);
            app.BreakdownVoltage.Value = -5;
            app.Wm2Label_4 = uilabel(app.CellTab);
            app.Wm2Label_4.Text = '[W/m^2]';
            app.VLabel_4 = uilabel(app.CellTab);
            app.VLabel_4.Text = '[V]';
            app.StringTab = uitab(app.TabGroup);
            app.StringTab.Title = 'String';
            app.figString = uiaxes(app.StringTab);
            title(app.figString, 'Title')
            xlabel(app.figString, 'X')
            ylabel(app.figString, 'Y')
            zlabel(app.figString, 'Z')
            colormap(app.figString, 'parula')
            app.IrradianceshadedcellsLabel = uilabel(app.StringTab);
            app.IrradianceshadedcellsLabel.Text = 'Irradiance shaded cells';
            app.BreakdownvoltageLabel = uilabel(app.StringTab);
            app.BreakdownvoltageLabel.Text = 'Breakdown voltage';
            app.Image = uiimage(app.StringTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Figures', 'FigureString.png');
            app.ShadedString_1 = uicheckbox(app.StringTab);
            app.ShadedString_1.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_1, true);
            app.ShadedString_1.Text = '';
            app.ShadedString_2 = uicheckbox(app.StringTab);
            app.ShadedString_2.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_2, true);
            app.ShadedString_2.Text = '';
            app.ShadedString_3 = uicheckbox(app.StringTab);
            app.ShadedString_3.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_3, true);
            app.ShadedString_3.Text = '';
            app.ShadedString_4 = uicheckbox(app.StringTab);
            app.ShadedString_4.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_4, true);
            app.ShadedString_4.Text = '';
            app.ShadedString_5 = uicheckbox(app.StringTab);
            app.ShadedString_5.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_5, true);
            app.ShadedString_5.Text = '';
            app.ShadedString_6 = uicheckbox(app.StringTab);
            app.ShadedString_6.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_6, true);
            app.ShadedString_6.Text = '';
            app.ShadedString_7 = uicheckbox(app.StringTab);
            app.ShadedString_7.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_7, true);
            app.ShadedString_7.Text = '';
            app.ShadedString_8 = uicheckbox(app.StringTab);
            app.ShadedString_8.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_8, true);
            app.ShadedString_8.Text = '';
            app.ShadedString_9 = uicheckbox(app.StringTab);
            app.ShadedString_9.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_9, true);
            app.ShadedString_9.Text = '';
            app.ShadedString_10 = uicheckbox(app.StringTab);
            app.ShadedString_10.ValueChangedFcn = createCallbackFcn(app, @ChangeStringShaded_10, true);
            app.ShadedString_10.Text = '';
            app.Irradiance_String = uieditfield(app.StringTab, 'numeric');
            app.Irradiance_String.ValueChangedFcn = createCallbackFcn(app, @ChangeIrradiance_String, true);
            app.Irradiance_String.Value = 500;
            app.BreakdownVoltage_String = uieditfield(app.StringTab, 'numeric');
            app.BreakdownVoltage_String.ValueChangedFcn = createCallbackFcn(app, @ChangeBreakdownVoltage_String, true);
            app.BreakdownVoltage_String.Value = -5;
            app.Wm2Label = uilabel(app.StringTab);
            app.Wm2Label.Text = '[W/m^2]';
            app.VLabel = uilabel(app.StringTab);
            app.VLabel.Text = '[V]';
            app.VoltageRangeString = uislider(app.StringTab, 'range');
            app.VoltageRangeString.Limits = [-10 10];
            app.VoltageRangeString.ValueChangedFcn = createCallbackFcn(app, @ChangeVrangeString, true);
            app.VoltageRangeString.Value = [-10 10];
            app.VoltagerangeVLabel = uilabel(app.StringTab);
            app.VoltagerangeVLabel.Text = 'Voltage range [V]';
            app.InidicatewhichcellareshadedLabel = uilabel(app.StringTab);
            app.InidicatewhichcellareshadedLabel.Text = 'Inidicate which cell are shaded';
            app.ModuleseriesTab = uitab(app.TabGroup);
            app.ModuleseriesTab.Title = 'Module-series';
            app.figSeries = uiaxes(app.ModuleseriesTab);
            title(app.figSeries, 'Title')
            xlabel(app.figSeries, 'X')
            ylabel(app.figSeries, 'Y')
            zlabel(app.figSeries, 'Z')
            colormap(app.figSeries, 'parula')
            app.Image2 = uiimage(app.ModuleseriesTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Figures', 'FigureModuleSeries.png');
            app.ShadedSeries_61 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_61.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_61, true);
            app.ShadedSeries_61.Text = '';
            app.ShadedSeries_72 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_72.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_72, true);
            app.ShadedSeries_72.Text = '';
            app.ShadedSeries_62 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_62.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_62, true);
            app.ShadedSeries_62.Text = '';
            app.ShadedSeries_63 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_63.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_63, true);
            app.ShadedSeries_63.Text = '';
            app.ShadedSeries_64 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_64.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_64, true);
            app.ShadedSeries_64.Text = '';
            app.ShadedSeries_65 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_65.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_65, true);
            app.ShadedSeries_65.Text = '';
            app.ShadedSeries_66 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_66.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_66, true);
            app.ShadedSeries_66.Text = '';
            app.ShadedSeries_67 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_67.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_67, true);
            app.ShadedSeries_67.Text = '';
            app.ShadedSeries_68 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_68.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_68, true);
            app.ShadedSeries_68.Text = '';
            app.ShadedSeries_69 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_69.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_69, true);
            app.ShadedSeries_69.Text = '';
            app.ShadedSeries_70 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_70.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_70, true);
            app.ShadedSeries_70.Text = '';
            app.ShadedSeries_71 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_71.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_71, true);
            app.ShadedSeries_71.Text = '';
            app.ShadedSeries_49 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_49.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_49, true);
            app.ShadedSeries_49.Text = '';
            app.ShadedSeries_60 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_60.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_60, true);
            app.ShadedSeries_60.Text = '';
            app.ShadedSeries_50 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_50.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_50, true);
            app.ShadedSeries_50.Text = '';
            app.ShadedSeries_51 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_51.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_51, true);
            app.ShadedSeries_51.Text = '';
            app.ShadedSeries_52 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_52.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_52, true);
            app.ShadedSeries_52.Text = '';
            app.ShadedSeries_53 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_53.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_53, true);
            app.ShadedSeries_53.Text = '';
            app.ShadedSeries_54 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_54.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_54, true);
            app.ShadedSeries_54.Text = '';
            app.ShadedSeries_55 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_55.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_55, true);
            app.ShadedSeries_55.Text = '';
            app.ShadedSeries_56 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_56.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_56, true);
            app.ShadedSeries_56.Text = '';
            app.ShadedSeries_57 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_57.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_57, true);
            app.ShadedSeries_57.Text = '';
            app.ShadedSeries_58 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_58.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_58, true);
            app.ShadedSeries_58.Text = '';
            app.ShadedSeries_59 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_59.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_59, true);
            app.ShadedSeries_59.Text = '';
            app.ShadedSeries_37 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_37.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_37, true);
            app.ShadedSeries_37.Text = '';
            app.ShadedSeries_48 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_48.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_48, true);
            app.ShadedSeries_48.Text = '';
            app.ShadedSeries_38 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_38.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_38, true);
            app.ShadedSeries_38.Text = '';
            app.ShadedSeries_39 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_39.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_39, true);
            app.ShadedSeries_39.Text = '';
            app.ShadedSeries_40 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_40.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_40, true);
            app.ShadedSeries_40.Text = '';
            app.ShadedSeries_41 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_41.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_41, true);
            app.ShadedSeries_41.Text = '';
            app.ShadedSeries_42 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_42.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_42, true);
            app.ShadedSeries_42.Text = '';
            app.ShadedSeries_43 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_43.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_43, true);
            app.ShadedSeries_43.Text = '';
            app.ShadedSeries_44 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_44.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_44, true);
            app.ShadedSeries_44.Text = '';
            app.ShadedSeries_45 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_45.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_45, true);
            app.ShadedSeries_45.Text = '';
            app.ShadedSeries_46 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_46.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_46, true);
            app.ShadedSeries_46.Text = '';
            app.ShadedSeries_47 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_47.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_47, true);
            app.ShadedSeries_47.Text = '';
            app.ShadedSeries_25 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_25.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_25, true);
            app.ShadedSeries_25.Text = '';
            app.ShadedSeries_36 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_36.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_36, true);
            app.ShadedSeries_36.Text = '';
            app.ShadedSeries_26 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_26.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_26, true);
            app.ShadedSeries_26.Text = '';
            app.ShadedSeries_27 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_27.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_27, true);
            app.ShadedSeries_27.Text = '';
            app.ShadedSeries_28 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_28.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_28, true);
            app.ShadedSeries_28.Text = '';
            app.ShadedSeries_29 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_29.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_29, true);
            app.ShadedSeries_29.Text = '';
            app.ShadedSeries_30 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_30.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_30, true);
            app.ShadedSeries_30.Text = '';
            app.ShadedSeries_31 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_31.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_31, true);
            app.ShadedSeries_31.Text = '';
            app.ShadedSeries_32 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_32.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_32, true);
            app.ShadedSeries_32.Text = '';
            app.ShadedSeries_33 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_33.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_33, true);
            app.ShadedSeries_33.Text = '';
            app.ShadedSeries_34 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_34.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_34, true);
            app.ShadedSeries_34.Text = '';
            app.ShadedSeries_35 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_35.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_35, true);
            app.ShadedSeries_35.Text = '';
            app.ShadedSeries_13 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_13.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_13, true);
            app.ShadedSeries_13.Text = '';
            app.ShadedSeries_24 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_24.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_24, true);
            app.ShadedSeries_24.Text = '';
            app.ShadedSeries_14 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_14.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_14, true);
            app.ShadedSeries_14.Text = '';
            app.ShadedSeries_15 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_15.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_15, true);
            app.ShadedSeries_15.Text = '';
            app.ShadedSeries_16 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_16.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_16, true);
            app.ShadedSeries_16.Text = '';
            app.ShadedSeries_17 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_17.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_17, true);
            app.ShadedSeries_17.Text = '';
            app.ShadedSeries_18 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_18.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_18, true);
            app.ShadedSeries_18.Text = '';
            app.ShadedSeries_19 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_19.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_19, true);
            app.ShadedSeries_19.Text = '';
            app.ShadedSeries_20 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_20.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_20, true);
            app.ShadedSeries_20.Text = '';
            app.ShadedSeries_21 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_21.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_21, true);
            app.ShadedSeries_21.Text = '';
            app.ShadedSeries_22 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_22.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_22, true);
            app.ShadedSeries_22.Text = '';
            app.ShadedSeries_23 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_23.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_23, true);
            app.ShadedSeries_23.Text = '';
            app.ShadedSeries_1 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_1.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_1, true);
            app.ShadedSeries_1.Text = '';
            app.ShadedSeries_12 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_12.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_12, true);
            app.ShadedSeries_12.Text = '';
            app.ShadedSeries_2 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_2.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_2, true);
            app.ShadedSeries_2.Text = '';
            app.ShadedSeries_3 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_3.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_3, true);
            app.ShadedSeries_3.Text = '';
            app.ShadedSeries_4 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_4.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_4, true);
            app.ShadedSeries_4.Text = '';
            app.ShadedSeries_5 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_5.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_5, true);
            app.ShadedSeries_5.Text = '';
            app.ShadedSeries_6 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_6.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_6, true);
            app.ShadedSeries_6.Text = '';
            app.ShadedSeries_7 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_7.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_7, true);
            app.ShadedSeries_7.Text = '';
            app.ShadedSeries_8 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_8.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_8, true);
            app.ShadedSeries_8.Text = '';
            app.ShadedSeries_9 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_9.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_9, true);
            app.ShadedSeries_9.Text = '';
            app.ShadedSeries_10 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_10.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_10, true);
            app.ShadedSeries_10.Text = '';
            app.ShadedSeries_11 = uicheckbox(app.ModuleseriesTab);
            app.ShadedSeries_11.ValueChangedFcn = createCallbackFcn(app, @ChangeSeriesShaded_11, true);
            app.ShadedSeries_11.Text = '';
            app.IrradianceshadedcellsLabel_2 = uilabel(app.ModuleseriesTab);
            app.IrradianceshadedcellsLabel_2.Text = 'Irradiance shaded cells';
            app.BreakdownvoltageLabel_2 = uilabel(app.ModuleseriesTab);
            app.BreakdownvoltageLabel_2.Text = 'Breakdown voltage';
            app.Irradiance_Series = uieditfield(app.ModuleseriesTab, 'numeric');
            app.Irradiance_Series.ValueChangedFcn = createCallbackFcn(app, @ChangeIrradiance_Series, true);
            app.Irradiance_Series.Value = 500;
            app.BreakdownVoltage_Series = uieditfield(app.ModuleseriesTab, 'numeric');
            app.BreakdownVoltage_Series.ValueChangedFcn = createCallbackFcn(app, @ChangeBreakdownVoltage_Series, true);
            app.BreakdownVoltage_Series.Value = -5;
            app.Wm2Label_2 = uilabel(app.ModuleseriesTab);
            app.Wm2Label_2.Text = '[W/m^2]';
            app.VLabel_2 = uilabel(app.ModuleseriesTab);
            app.VLabel_2.Text = '[V]';
            app.String1Label = uilabel(app.ModuleseriesTab);
            app.String1Label.Text = 'String 1';
            app.String2Label = uilabel(app.ModuleseriesTab);
            app.String2Label.Text = 'String 2';
            app.String3Label = uilabel(app.ModuleseriesTab);
            app.String3Label.Text = 'String 3';
            app.InidicatewhichcellareshadedLabel_2 = uilabel(app.ModuleseriesTab);
            app.InidicatewhichcellareshadedLabel_2.Text = 'Inidicate which cell are shaded';
            app.ModulebutterflyTab = uitab(app.TabGroup);
            app.ModulebutterflyTab.Title = 'Module-butterfly';
            app.figButterfly = uiaxes(app.ModulebutterflyTab);
            title(app.figButterfly, 'Title')
            xlabel(app.figButterfly, 'X')
            ylabel(app.figButterfly, 'Y')
            zlabel(app.figButterfly, 'Z')
            colormap(app.figButterfly, 'parula')
            app.Image2_2 = uiimage(app.ModulebutterflyTab);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'Figures', 'FigureModuleButterfly.png');
            app.ShadedButterfly_61 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_61.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_61, true);
            app.ShadedButterfly_61.Text = '';
            app.ShadedButterfly_72 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_72.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_72, true);
            app.ShadedButterfly_72.Text = '';
            app.ShadedButterfly_62 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_62.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_62, true);
            app.ShadedButterfly_62.Text = '';
            app.ShadedButterfly_63 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_63.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_63, true);
            app.ShadedButterfly_63.Text = '';
            app.ShadedButterfly_64 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_64.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_64, true);
            app.ShadedButterfly_64.Text = '';
            app.ShadedButterfly_65 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_65.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_65, true);
            app.ShadedButterfly_65.Text = '';
            app.ShadedButterfly_66 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_66.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_66, true);
            app.ShadedButterfly_66.Text = '';
            app.ShadedButterfly_67 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_67.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_67, true);
            app.ShadedButterfly_67.Text = '';
            app.ShadedButterfly_68 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_68.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_68, true);
            app.ShadedButterfly_68.Text = '';
            app.ShadedButterfly_69 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_69.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_69, true);
            app.ShadedButterfly_69.Text = '';
            app.ShadedButterfly_70 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_70.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_70, true);
            app.ShadedButterfly_70.Text = '';
            app.ShadedButterfly_71 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_71.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_71, true);
            app.ShadedButterfly_71.Text = '';
            app.ShadedButterfly_49 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_49.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_49, true);
            app.ShadedButterfly_49.Text = '';
            app.ShadedButterfly_60 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_60.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_60, true);
            app.ShadedButterfly_60.Text = '';
            app.ShadedButterfly_50 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_50.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_50, true);
            app.ShadedButterfly_50.Text = '';
            app.ShadedButterfly_51 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_51.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_51, true);
            app.ShadedButterfly_51.Text = '';
            app.ShadedButterfly_52 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_52.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_52, true);
            app.ShadedButterfly_52.Text = '';
            app.ShadedButterfly_53 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_53.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_53, true);
            app.ShadedButterfly_53.Text = '';
            app.ShadedButterfly_54 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_54.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_54, true);
            app.ShadedButterfly_54.Text = '';
            app.ShadedButterfly_55 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_55.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_55, true);
            app.ShadedButterfly_55.Text = '';
            app.ShadedButterfly_56 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_56.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_56, true);
            app.ShadedButterfly_56.Text = '';
            app.ShadedButterfly_57 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_57.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_57, true);
            app.ShadedButterfly_57.Text = '';
            app.ShadedButterfly_58 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_58.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_58, true);
            app.ShadedButterfly_58.Text = '';
            app.ShadedButterfly_59 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_59.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_59, true);
            app.ShadedButterfly_59.Text = '';
            app.ShadedButterfly_37 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_37.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_37, true);
            app.ShadedButterfly_37.Text = '';
            app.ShadedButterfly_48 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_48.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_48, true);
            app.ShadedButterfly_48.Text = '';
            app.ShadedButterfly_38 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_38.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_38, true);
            app.ShadedButterfly_38.Text = '';
            app.ShadedButterfly_39 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_39.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_39, true);
            app.ShadedButterfly_39.Text = '';
            app.ShadedButterfly_40 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_40.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_40, true);
            app.ShadedButterfly_40.Text = '';
            app.ShadedButterfly_41 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_41.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_41, true);
            app.ShadedButterfly_41.Text = '';
            app.ShadedButterfly_42 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_42.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_42, true);
            app.ShadedButterfly_42.Text = '';
            app.ShadedButterfly_43 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_43.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_43, true);
            app.ShadedButterfly_43.Text = '';
            app.ShadedButterfly_44 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_44.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_44, true);
            app.ShadedButterfly_44.Text = '';
            app.ShadedButterfly_45 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_45.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_45, true);
            app.ShadedButterfly_45.Text = '';
            app.ShadedButterfly_46 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_46.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_46, true);
            app.ShadedButterfly_46.Text = '';
            app.ShadedButterfly_47 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_47.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_47, true);
            app.ShadedButterfly_47.Text = '';
            app.ShadedButterfly_25 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_25.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_25, true);
            app.ShadedButterfly_25.Text = '';
            app.ShadedButterfly_36 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_36.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_36, true);
            app.ShadedButterfly_36.Text = '';
            app.ShadedButterfly_26 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_26.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_26, true);
            app.ShadedButterfly_26.Text = '';
            app.ShadedButterfly_27 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_27.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_27, true);
            app.ShadedButterfly_27.Text = '';
            app.ShadedButterfly_28 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_28.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_28, true);
            app.ShadedButterfly_28.Text = '';
            app.ShadedButterfly_29 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_29.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_29, true);
            app.ShadedButterfly_29.Text = '';
            app.ShadedButterfly_30 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_30.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_30, true);
            app.ShadedButterfly_30.Text = '';
            app.ShadedButterfly_31 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_31.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_31, true);
            app.ShadedButterfly_31.Text = '';
            app.ShadedButterfly_32 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_32.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_32, true);
            app.ShadedButterfly_32.Text = '';
            app.ShadedButterfly_33 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_33.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_33, true);
            app.ShadedButterfly_33.Text = '';
            app.ShadedButterfly_34 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_34.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_34, true);
            app.ShadedButterfly_34.Text = '';
            app.ShadedButterfly_35 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_35.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_35, true);
            app.ShadedButterfly_35.Text = '';
            app.ShadedButterfly_13 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_13.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_13, true);
            app.ShadedButterfly_13.Text = '';
            app.ShadedButterfly_24 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_24.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_24, true);
            app.ShadedButterfly_24.Text = '';
            app.ShadedButterfly_14 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_14.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_14, true);
            app.ShadedButterfly_14.Text = '';
            app.ShadedButterfly_15 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_15.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_15, true);
            app.ShadedButterfly_15.Text = '';
            app.ShadedButterfly_16 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_16.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_16, true);
            app.ShadedButterfly_16.Text = '';
            app.ShadedButterfly_17 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_17.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_17, true);
            app.ShadedButterfly_17.Text = '';
            app.ShadedButterfly_18 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_18.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_18, true);
            app.ShadedButterfly_18.Text = '';
            app.ShadedButterfly_19 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_19.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_19, true);
            app.ShadedButterfly_19.Text = '';
            app.ShadedButterfly_20 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_20.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_20, true);
            app.ShadedButterfly_20.Text = '';
            app.ShadedButterfly_21 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_21.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_21, true);
            app.ShadedButterfly_21.Text = '';
            app.ShadedButterfly_22 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_22.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_22, true);
            app.ShadedButterfly_22.Text = '';
            app.ShadedButterfly_23 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_23.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_23, true);
            app.ShadedButterfly_23.Text = '';
            app.ShadedButterfly_1 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_1.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_1, true);
            app.ShadedButterfly_1.Text = '';
            app.ShadedButterfly_12 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_12.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_12, true);
            app.ShadedButterfly_12.Text = '';
            app.ShadedButterfly_2 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_2.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_2, true);
            app.ShadedButterfly_2.Text = '';
            app.ShadedButterfly_3 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_3.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_3, true);
            app.ShadedButterfly_3.Text = '';
            app.ShadedButterfly_4 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_4.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_4, true);
            app.ShadedButterfly_4.Text = '';
            app.ShadedButterfly_5 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_5.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_5, true);
            app.ShadedButterfly_5.Text = '';
            app.ShadedButterfly_6 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_6.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_6, true);
            app.ShadedButterfly_6.Text = '';
            app.ShadedButterfly_7 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_7.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_7, true);
            app.ShadedButterfly_7.Text = '';
            app.ShadedButterfly_8 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_8.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_8, true);
            app.ShadedButterfly_8.Text = '';
            app.ShadedButterfly_9 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_9.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_9, true);
            app.ShadedButterfly_9.Text = '';
            app.ShadedButterfly_10 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_10.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_10, true);
            app.ShadedButterfly_10.Text = '';
            app.ShadedButterfly_11 = uicheckbox(app.ModulebutterflyTab);
            app.ShadedButterfly_11.ValueChangedFcn = createCallbackFcn(app, @ChangeButterflyShaded_11, true);
            app.ShadedButterfly_11.Text = '';
            app.IrradianceshadedcellsLabel_3 = uilabel(app.ModulebutterflyTab);
            app.IrradianceshadedcellsLabel_3.Text = 'Irradiance shaded cells';
            app.BreakdownvoltageLabel_3 = uilabel(app.ModulebutterflyTab);
            app.BreakdownvoltageLabel_3.Text = 'Breakdown voltage';
            app.Irradiance_Butterfly = uieditfield(app.ModulebutterflyTab, 'numeric');
            app.Irradiance_Butterfly.ValueChangedFcn = createCallbackFcn(app, @ChangeIrradiance_Butterfly, true);
            app.Irradiance_Butterfly.Value = 500;
            app.BreakdownVoltage_Butterfly = uieditfield(app.ModulebutterflyTab, 'numeric');
            app.BreakdownVoltage_Butterfly.ValueChangedFcn = createCallbackFcn(app, @changeBreakdownVoltage_Butterfly, true);
            app.BreakdownVoltage_Butterfly.Value = -5;
            app.Wm2Label_3 = uilabel(app.ModulebutterflyTab);
            app.Wm2Label_3.Text = '[W/m^2]';
            app.VLabel_3 = uilabel(app.ModulebutterflyTab);
            app.VLabel_3.Text = '[V]';
            app.String1aLabel = uilabel(app.ModulebutterflyTab);
            app.String1aLabel.Text = 'String 1a';
            app.String2aLabel = uilabel(app.ModulebutterflyTab);
            app.String2aLabel.Text = 'String 2a';
            app.String3aLabel = uilabel(app.ModulebutterflyTab);
            app.String3aLabel.Text = 'String 3a';
            app.String1bLabel = uilabel(app.ModulebutterflyTab);
            app.String1bLabel.Text = 'String 1b';
            app.String2bLabel = uilabel(app.ModulebutterflyTab);
            app.String2bLabel.Text = 'String 2b';
            app.String3bLabel = uilabel(app.ModulebutterflyTab);
            app.String3bLabel.Text = 'String 3b';
            app.PlotaandbseperateCheckBox = uicheckbox(app.ModulebutterflyTab);
            app.PlotaandbseperateCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangePlotSeperate, true);
            app.PlotaandbseperateCheckBox.Text = 'Plot a and b seperate';
            app.InidicatewhichcellareshadedLabel_3 = uilabel(app.ModulebutterflyTab);
            app.InidicatewhichcellareshadedLabel_3.Text = 'Inidicate which cell are shaded';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualizeShading_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end